classdef appSIR_all_countriesV02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BetaSliderLabel       matlab.ui.control.Label
        BetaSlider            matlab.ui.control.Slider
        GammaSliderLabel      matlab.ui.control.Label
        GammaSlider           matlab.ui.control.Slider
        CountryDropDownLabel  matlab.ui.control.Label
        CountryDropDown       matlab.ui.control.DropDown
        UITable               matlab.ui.control.Table
        OptimizeButton        matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         global MyISO Dates Cases;
         MyISO='IRL';
         Country_SetupV03;
         plot(app.UIAxes, Dates, Cases, 'o', 'LineWidth', 2);         
         app.BetaSlider.Value = Beta_UI;
         app.GammaSlider.Value = Gamma_UI;
        end

        % Value changed function: BetaSlider
        function BetaSliderValueChanged(app, event)
         global Beta_UI Gamma_UI Dates Cases domain;
         Beta_UI = app.BetaSlider.Value;
         R0 = Beta_UI/Gamma_UI;
         update = [Beta_UI Gamma_UI R0];
         app.UITable.Data = update;
         [~,I,~] = SIR_BetaGamma();
         t = linspace(domain(1),domain(end),1001);
         plot(app.UIAxes,t, I(t), Dates, Cases, 'o', 'LineWidth', 2);

        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
         global Beta_UI Gamma_UI Dates Cases domain;
         Gamma_UI = app.GammaSlider.Value;
         R0 = Beta_UI/Gamma_UI;
         update = [Beta_UI Gamma_UI R0];
         app.UITable.Data = update;
         [~,I,~] = SIR_BetaGamma();
         t = linspace(domain(1),domain(end),1001);
         plot(app.UIAxes,t, I(t), Dates, Cases, 'o', 'LineWidth', 2);

        end

        % Value changed function: CountryDropDown
        function CountryDropDownValueChanged(app, event)
         global Beta_UI Gamma_UI Dates Cases domain;
         global MyISO Country Country_to_ISO;
         Country = app.CountryDropDown.Value;
         
%          if (isequal(value,'Ireland'))
%             MyISO = 'IRL';
%          elseif (isequal(value,'Austria'))
%             MyISO = 'AUT';
%          elseif (isequal(value,'Belgium'))
%             MyISO = 'BEL';
%          elseif (isequal(value,'Denmark'))
%             MyISO = 'DNK';
%          elseif (isequal(value,'Italy'))
%             MyISO = 'ITA';
%          elseif (isequal(value,'Afghanistan'))
%             MyISO = 'AFG';
%          elseif (isequal(value,'Australia'))
%             MyISO = 'AUS';
%          elseif (isequal(value,'Spain'))
%          MyISO = 'ESP';
%          elseif (isequal(value,'France'))
%          MyISO = 'FRA';
%          elseif (isequal(value,'United Kingdom'))
%          MyISO = 'GBR';
%          elseif (isequal(value,'Latvia'))
%          MyISO = 'LVA';
%          elseif (isequal(value,'Slovakia'))
%          MyISO = 'SVK';
%          elseif (isequal(value,'China'))
%          MyISO = 'CHN';
%          elseif (isequal(value,'Portugal'))
%          MyISO = 'PRT';
%          elseif (isequal(value,'Switzerland'))
%          MyISO = 'CHE';
%          elseif (isequal(value,'Norway'))
%          MyISO = 'NOR';
%          elseif (isequal(value,'Finland'))
%          MyISO = 'FIN';
%          elseif (isequal(value,'Russia'))
%          MyISO = 'RUS';
%          elseif (isequal(value,'Brazil'))
%          MyISO = 'BRA';
%          elseif (isequal(value,'Mexico'))
%          MyISO = 'MEX';
%          elseif (isequal(value,'Poland'))
%          MyISO = 'POL';
%          elseif (isequal(value,'United Arab Emirates'))
%          MyISO = 'ARE';
%          elseif (isequal(value,'India'))
%          MyISO = 'IND';
%          elseif (isequal(value,'Peru'))
%          MyISO = 'PER';
%          elseif (isequal(value,'Chile'))
%          MyISO = 'CHL';
%          elseif (isequal(value,'Iran'))
%          MyISO = 'IRN';
%          elseif (isequal(value,'Pakistan'))
%          MyISO = 'PAK';
%          elseif (isequal(value,'South Africa'))
%          MyISO = 'ZAF';
%          elseif (isequal(value,'Saudi Arabia'))
%          MyISO = 'SAU';
%          else
%             MyISO = 'IRL';
%          end
         MyISO = Country_to_ISO(Country);
         fprintf('Country=%s, ISO=%s\n', Country, MyISO);
         Country_SetupV03;
         app.BetaSlider.Value = Beta_UI;
         app.GammaSlider.Value = Gamma_UI;
         [~,I,~] = SIR_BetaGamma();
         t = linspace(domain(1),domain(end),1001);
         plot(app.UIAxes,t, I(t), Dates, Cases, 'o', 'LineWidth', 2);
         app.UIAxes.YLim = [0 max(Cases)*1.1];
         
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
         global Beta_UI Gamma_UI Dates Cases domain;
         options = optimset('Display', 'iter', 'Tolx', 1.00e-08, 'TolF', 1.00e-08);  
         opt_C = lsqnonlin(@(C)SIR_objective(C), [Beta_UI, Gamma_UI], [0,0], [inf,inf], options);
         disp(opt_C)
         Beta_UI = opt_C(1);  
         Gamma_UI = opt_C(2);  
         app.BetaSlider.Value = Beta_UI;
         app.GammaSlider.Value = Gamma_UI;
         [~,I,~] = SIR_BetaGamma();
         t = linspace(domain(1),domain(end),1001);
         plot(app.UIAxes,t, I(t), Dates, Cases, 'o', 'LineWidth', 2);
         app.UIAxes.YLim = [0 max(Cases)*1.1];
         R0 = Beta_UI/Gamma_UI;
         update = [Beta_UI Gamma_UI R0];
         app.UITable.Data = update;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BetaSliderLabel = uilabel(app.UIFigure);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.UIFigure);
            app.BetaSlider.Limits = [0 25];
            app.BetaSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @BetaSliderValueChanged, true);
            app.BetaSlider.MinorTicks = [];
            app.BetaSlider.Value = 9.6;
            app.GammaSliderLabel = uilabel(app.UIFigure);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.UIFigure);
            app.GammaSlider.Limits = [0 25];
            app.GammaSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.GammaSlider.MinorTicks = [];
            app.GammaSlider.Value = 10;
            app.CountryDropDownLabel = uilabel(app.UIFigure);
            app.CountryDropDownLabel.Text = 'Country';
            app.CountryDropDown = uidropdown(app.UIFigure);
            app.CountryDropDown.Items = {'Afghanistan', 'Albania', 'Algeria', 'Andorra', 'Angola', 'Anguilla', 'Antigua and Barbuda', 'Argentina', 'Armenia', 'Aruba', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bermuda', 'Bhutan', 'Bolivia', 'Bonaire Sint Eustatius and Saba', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'British Virgin Islands', 'Brunei', 'Bulgaria', 'Burkina Faso', 'Burundi', 'Cambodia', 'Cameroon', 'Canada', 'Cape Verde', 'Cayman Islands', 'Central African Republic', 'Chad', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo', 'Costa Rica', 'Cote d''Ivoire', 'Croatia', 'Cuba', 'Curacao', 'Cyprus', 'Czech Republic', 'Democratic Republic of Congo', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'Ecuador', 'Egypt', 'El Salvador', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Ethiopia', 'Faeroe Islands', 'Falkland Islands', 'Fiji', 'Finland', 'France', 'French Polynesia', 'Gabon', 'Gambia', 'Georgia', 'Germany', 'Ghana', 'Gibraltar', 'Greece', 'Greenland', 'Grenada', 'Guam', 'Guatemala', 'Guernsey', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'Honduras', 'Hong Kong', 'Hungary', 'Iceland', 'India', 'Indonesia', 'International', 'Iran', 'Iraq', 'Ireland', 'Isle of Man', 'Israel', 'Italy', 'Jamaica', 'Japan', 'Jersey', 'Jordan', 'Kazakhstan', 'Kenya', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Laos', 'Latvia', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Macedonia', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Mauritania', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Montserrat', 'Morocco', 'Mozambique', 'Myanmar', 'Namibia', 'Nepal', 'Netherlands', 'New Caledonia', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'Northern Mariana Islands', 'Norway', 'Oman', 'Pakistan', 'Palestine', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Rwanda', 'Saint Kitts and Nevis', 'Saint Lucia', 'Saint Vincent and the Grenadines', 'San Marino', 'Sao Tome and Principe', 'Saudi Arabia', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leone', 'Singapore', 'Sint Maarten (Dutch part)', 'Slovakia', 'Slovenia', 'Somalia', 'South Africa', 'South Korea', 'South Sudan', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Swaziland', 'Sweden', 'Switzerland', 'Syria', 'Taiwan', 'Tajikistan', 'Tanzania', 'Thailand', 'Timor', 'Togo', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Turks and Caicos Islands', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'United States', 'United States Virgin Islands', 'Uruguay', 'Uzbekistan', 'Vatican', 'Venezuela', 'Vietnam', 'Western Sahara', 'World', 'Yemen', 'Zambia', 'Zimbabwe'};
            app.CountryDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryDropDownValueChanged, true);
            app.CountryDropDown.Value = 'Afghanistan';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Beta'; 'Gamma'; 'R_0'};
            app.UITable.RowName = {};
            app.OptimizeButton = uibutton(app.UIFigure, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SIR Model')
            xlabel(app.UIAxes, 'Days')
            ylabel(app.UIAxes, 'Daily Cases')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSIR_all_countriesV02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end